classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PositionsTab                    matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        VelocitiesTab                   matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        RotationcontrolTab              matlab.ui.container.Tab
        RotationspeedcontrolLabel       matlab.ui.control.Label
        Motor1speedLabel                matlab.ui.control.Label
        Motor1speedSlider               matlab.ui.control.Slider
        Motor1speedEditField            matlab.ui.control.NumericEditField
        RotationswitchSwitchLabel       matlab.ui.control.Label
        RotationswitchSwitch            matlab.ui.control.ToggleSwitch
        BrakeswitchSwitchLabel          matlab.ui.control.Label
        Brake1switchSwitch              matlab.ui.control.Switch
        BrakeswitchSwitch_2Label        matlab.ui.control.Label
        Brake2switchSwitch              matlab.ui.control.Switch
        Motor2speedEditField            matlab.ui.control.NumericEditField
        Motor2speedSliderLabel          matlab.ui.control.Label
        Motor2speedSlider               matlab.ui.control.Slider
        PIDpositioncontrolTab           matlab.ui.container.Tab
        KpEditFieldLabel                matlab.ui.control.Label
        KpEditField                     matlab.ui.control.NumericEditField
        KiEditFieldLabel                matlab.ui.control.Label
        KiEditField                     matlab.ui.control.NumericEditField
        KdEditFieldLabel                matlab.ui.control.Label
        KdEditField                     matlab.ui.control.NumericEditField
        KpEditField_2Label              matlab.ui.control.Label
        KpEditField_2                   matlab.ui.control.NumericEditField
        KiEditField_2Label              matlab.ui.control.Label
        KiEditField_2                   matlab.ui.control.NumericEditField
        KdEditField_2Label              matlab.ui.control.Label
        KdEditField_2                   matlab.ui.control.NumericEditField
        BrakeswitchSwitchLabel_2        matlab.ui.control.Label
        Brake1switchSwitch_2            matlab.ui.control.Switch
        BrakeswitchSwitch_2Label_2      matlab.ui.control.Label
        Brake2switchSwitch_2            matlab.ui.control.Switch
        RunningswitchSwitchLabel        matlab.ui.control.Label
        RunningswitchSwitch             matlab.ui.control.ToggleSwitch
        Joint1EditFieldLabel            matlab.ui.control.Label
        Joint1EditField                 matlab.ui.control.NumericEditField
        Joint2EditFieldLabel            matlab.ui.control.Label
        Joint2EditField                 matlab.ui.control.NumericEditField
        CostumedtrajectoryEditFieldLabel  matlab.ui.control.Label
        CostumedtrajectoryEditField     matlab.ui.control.EditField
        DefaulttrajectoryDropDownLabel  matlab.ui.control.Label
        DefaulttrajectoryDropDown       matlab.ui.control.DropDown
        SimulateButton                  matlab.ui.control.Button
        JointinitialpositionsLabel      matlab.ui.control.Label
        PIDpositioncontrolLabel         matlab.ui.control.Label
        DesiredtrajectoryLabel          matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        RobotpositionSwitchLabel        matlab.ui.control.Label
        RobotpositionSwitch             matlab.ui.control.Switch
        PositionsreadingTab             matlab.ui.container.Tab
        ReadButton                      matlab.ui.control.Button
        Joint1angleLabel                matlab.ui.control.Label
        Joint1rad                       matlab.ui.control.Label
        Joint2angleLabel                matlab.ui.control.Label
        Joint2rad                       matlab.ui.control.Label
        radsLabel                       matlab.ui.control.Label
        radsLabel_2                     matlab.ui.control.Label
        Joint1degree                    matlab.ui.control.Label
        degreesLabel                    matlab.ui.control.Label
        Joint2degree                    matlab.ui.control.Label
        degreesLabel_2                  matlab.ui.control.Label
        ReadpositionsonceLabel          matlab.ui.control.Label
        ReadforawhileLabel              matlab.ui.control.Label
        PositionsangleswillbeplottedinthepanelbelowLabel  matlab.ui.control.Label
        ReadandplotSwitchLabel          matlab.ui.control.Label
        ReadandplotSwitch               matlab.ui.control.ToggleSwitch
        LampLabel                       matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Lamp2Label                      matlab.ui.control.Label
        Lamp2                           matlab.ui.control.Lamp
        Lamp3Label                      matlab.ui.control.Label
        Lamp3                           matlab.ui.control.Lamp
        CommandOptionsLabel             matlab.ui.control.Label
        COMportEditFieldLabel           matlab.ui.control.Label
        COMportEditField                matlab.ui.control.EditField
        ConnectButton                   matlab.ui.control.StateButton
        GUIforR2RRobotLabel             matlab.ui.control.Label
        UIAxes5                         matlab.ui.control.UIAxes
        CalibrationButton               matlab.ui.control.Button
    end

    properties (Access = private)
        userserial % Description
    end

    properties (Access = public)
        v % Description
    end

    methods (Access = private)   
        function velocityProcess(app)
            if strcmp(app.RotationswitchSwitch.Value, 'On')
                if strcmp(app.Brake1switchSwitch.Value, 'Off') && strcmp(app.Brake2switchSwitch.Value, 'Off')
                    velocitySend(app.v, 3, app.userserial);
                elseif strcmp(app.Brake1switchSwitch.Value, 'Off') && strcmp(app.Brake2switchSwitch.Value, 'On')
                    velocitySend(app.v, 1, app.userserial);
                elseif strcmp(app.Brake1switchSwitch.Value, 'On') && strcmp(app.Brake2switchSwitch.Value, 'Off')
                    velocitySend(app.v, 2, app.userserial);
                else
                    velocitySend(app.v, 4, app.userserial);
                end
            end
        end
    end

    methods (Access = private)

        % Value changing function: Motor1speedSlider
        function Motor1speedSliderValueChanging(app, event)
            value = event.Value;
            app.Motor1speedEditField.Value = value;
            if strcmp(app.RotationswitchSwitch.Value, 'On')
                app.v(1) = value;
                velocityProcess(app);
            end
        end

        % Value changed function: Motor1speedEditField
        function Motor1speedEditFieldValueChanged(app, event)
            value = event.Value;
            if value > 100
                value = 100;
                app.Motor1speedEditField.Value = 100;
            elseif value < -100
                value = -100;
                app.Motor1speedEditField.Value = -100;
            end
            app.Motor1speedSlider.Value = value;
            if strcmp(app.RotationswitchSwitch.Value, 'On')
                app.v(1) = value;
                velocityProcess(app);
            end           
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.COMportEditField.Value;
            if app.ConnectButton.Value == 1
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
                if ismember(value, seriallist)
                    app.userserial = serialConfig(value);
                    fopen(app.userserial);
                    app.RotationswitchSwitch.Enable = 'on';
                    app.CalibrationButton.Enable = 'on';
                    app.ReadButton.Enable = 'on';
                else
                    app.ConnectButton.Value = 0;
                end
            else
                fclose(app.userserial);
                app.RotationswitchSwitch.Value = 'Off';
                app.RotationswitchSwitch.Enable = 'off';
                app.CalibrationButton.Enable = 'off';
                app.ReadButton.Enable = 'off';
            end
        end

        % Value changing function: Motor2speedSlider
        function Motor2speedSliderValueChanging(app, event)
            value = event.Value;
            app.Motor2speedEditField.Value = value;
            if strcmp(app.RotationswitchSwitch.Value, 'On')
                app.v(2) = value;
                velocityProcess(app);
            end        
        end

        % Value changed function: Motor2speedEditField
        function Motor2speedEditFieldValueChanged(app, event)
            value = event.Value;
            if value > 100
                value = 100;
                app.Motor2speedEditField.Value = 100;
            elseif value < -100
                value = -100;
                app.Motor2speedEditField.Value = -100;
            end
            app.Motor2speedSlider.Value = value;
            if strcmp(app.RotationswitchSwitch.Value, 'On')
                app.v(2) = value;
                velocityProcess(app);
            end
        end

        % Value changed function: RotationswitchSwitch
        function RotationswitchSwitchValueChanged(app, event)
            value = event.Value;
            if strcmp(value, 'On')
                app.v = [app.Motor1speedEditField.Value, app.Motor2speedEditField.Value];
            else
                app.RotationswitchSwitch.Value = 'Off';
                app.v = [0, 0]; 
            end
            velocityProcess(app);            
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            window = helpdlg('Please move and hold the joints at their zero positions until the calibration finishes.');
            waitfor(window);
            setZero(app.userserial);
            msgbox('Calibration finished!', 'Success');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.userserial)
                finishup = onCleanup(@()fclose(app.userserial)); %closes serial on termination!
            end
            delete(app);           
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            p = positionRead(3, app.userserial);
            app.Joint1rad.Text = num2str(p(1));
            app.Joint1degree.Text = num2str(p(2));
            app.Joint2rad.Text = num2str(p(3));
            app.Joint2degree.Text = num2str(p(4));
        end

        % Value changed function: ReadandplotSwitch
        function ReadandplotSwitchValueChanged(app, event)
            value = app.ReadandplotSwitch.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PositionsTab = uitab(app.TabGroup);
            app.PositionsTab.Title = 'Positions';
            app.UIAxes2 = uiaxes(app.PositionsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'Theta 1')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.ZLim = [0 1];
            app.UIAxes2.CLim = [0 1];
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.ZTick = [0 0.5 1];
            app.UIAxes2_2 = uiaxes(app.PositionsTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 't')
            ylabel(app.UIAxes2_2, 'Theta 2')
            app.UIAxes2_2.XLim = [0 1];
            app.UIAxes2_2.YLim = [0 1];
            app.UIAxes2_2.ZLim = [0 1];
            app.UIAxes2_2.CLim = [0 1];
            app.UIAxes2_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2_2.ZTick = [0 0.5 1];
            app.VelocitiesTab = uitab(app.TabGroup);
            app.VelocitiesTab.Title = 'Velocities';
            app.UIAxes3 = uiaxes(app.VelocitiesTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.XLim = [0 1];
            app.UIAxes3.YLim = [0 1];
            app.UIAxes3.ZLim = [0 1];
            app.UIAxes3.CLim = [0 1];
            app.UIAxes3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes3.YTick = [0 0.5 1];
            app.UIAxes3.ZTick = [0 0.5 1];
            app.UIAxes4 = uiaxes(app.VelocitiesTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes4.XLim = [0 1];
            app.UIAxes4.YLim = [0 1];
            app.UIAxes4.ZLim = [0 1];
            app.UIAxes4.CLim = [0 1];
            app.UIAxes4.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes4.YTick = [0 0.5 1];
            app.UIAxes4.ZTick = [0 0.5 1];
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.RotationcontrolTab = uitab(app.TabGroup2);
            app.RotationcontrolTab.Title = 'Rotation control';
            app.RotationspeedcontrolLabel = uilabel(app.RotationcontrolTab);
            app.RotationspeedcontrolLabel.Text = 'Rotation speed control';
            app.Motor1speedLabel = uilabel(app.RotationcontrolTab);
            app.Motor1speedLabel.Text = 'Motor 1 speed';
            app.Motor1speedSlider = uislider(app.RotationcontrolTab);
            app.Motor1speedSlider.Limits = [-100 100];
            app.Motor1speedSlider.ValueChangingFcn = createCallbackFcn(app, @Motor1speedSliderValueChanging, true);
            app.Motor1speedEditField = uieditfield(app.RotationcontrolTab, 'numeric');
            app.Motor1speedEditField.ValueChangedFcn = createCallbackFcn(app, @Motor1speedEditFieldValueChanged, true);
            app.RotationswitchSwitchLabel = uilabel(app.RotationcontrolTab);
            app.RotationswitchSwitchLabel.Text = {'Rotation'; 'switch'};
            app.RotationswitchSwitch = uiswitch(app.RotationcontrolTab, 'toggle');
            app.RotationswitchSwitch.ValueChangedFcn = createCallbackFcn(app, @RotationswitchSwitchValueChanged, true);
            app.RotationswitchSwitch.Enable = 'off';
            app.BrakeswitchSwitchLabel = uilabel(app.RotationcontrolTab);
            app.BrakeswitchSwitchLabel.Text = 'Brake switch';
            app.Brake1switchSwitch = uiswitch(app.RotationcontrolTab, 'slider');
            app.BrakeswitchSwitch_2Label = uilabel(app.RotationcontrolTab);
            app.BrakeswitchSwitch_2Label.Text = 'Brake switch';
            app.Brake2switchSwitch = uiswitch(app.RotationcontrolTab, 'slider');
            app.Motor2speedEditField = uieditfield(app.RotationcontrolTab, 'numeric');
            app.Motor2speedEditField.ValueChangedFcn = createCallbackFcn(app, @Motor2speedEditFieldValueChanged, true);
            app.Motor2speedSliderLabel = uilabel(app.RotationcontrolTab);
            app.Motor2speedSliderLabel.Text = 'Motor 2 speed';
            app.Motor2speedSlider = uislider(app.RotationcontrolTab);
            app.Motor2speedSlider.Limits = [-100 100];
            app.Motor2speedSlider.ValueChangingFcn = createCallbackFcn(app, @Motor2speedSliderValueChanging, true);
            app.PIDpositioncontrolTab = uitab(app.TabGroup2);
            app.PIDpositioncontrolTab.Title = 'PID position control';
            app.KpEditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.KiEditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.KdEditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.KpEditField_2Label = uilabel(app.PIDpositioncontrolTab);
            app.KpEditField_2Label.Text = 'Kp';
            app.KpEditField_2 = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.KiEditField_2Label = uilabel(app.PIDpositioncontrolTab);
            app.KiEditField_2Label.Text = 'Ki';
            app.KiEditField_2 = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.KdEditField_2Label = uilabel(app.PIDpositioncontrolTab);
            app.KdEditField_2Label.Text = 'Kd';
            app.KdEditField_2 = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.BrakeswitchSwitchLabel_2 = uilabel(app.PIDpositioncontrolTab);
            app.BrakeswitchSwitchLabel_2.Text = 'Brake switch';
            app.Brake1switchSwitch_2 = uiswitch(app.PIDpositioncontrolTab, 'slider');
            app.BrakeswitchSwitch_2Label_2 = uilabel(app.PIDpositioncontrolTab);
            app.BrakeswitchSwitch_2Label_2.Text = 'Brake switch';
            app.Brake2switchSwitch_2 = uiswitch(app.PIDpositioncontrolTab, 'slider');
            app.RunningswitchSwitchLabel = uilabel(app.PIDpositioncontrolTab);
            app.RunningswitchSwitchLabel.Text = {'Running'; 'switch'};
            app.RunningswitchSwitch = uiswitch(app.PIDpositioncontrolTab, 'toggle');
            app.RunningswitchSwitch.Enable = 'off';
            app.Joint1EditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.CostumedtrajectoryEditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.CostumedtrajectoryEditFieldLabel.Text = 'Costumed trajectory';
            app.CostumedtrajectoryEditField = uieditfield(app.PIDpositioncontrolTab, 'text');
            app.DefaulttrajectoryDropDownLabel = uilabel(app.PIDpositioncontrolTab);
            app.DefaulttrajectoryDropDownLabel.Text = 'Default trajectory';
            app.DefaulttrajectoryDropDown = uidropdown(app.PIDpositioncontrolTab);
            app.SimulateButton = uibutton(app.PIDpositioncontrolTab, 'push');
            app.SimulateButton.Text = 'Simulate';
            app.JointinitialpositionsLabel = uilabel(app.PIDpositioncontrolTab);
            app.JointinitialpositionsLabel.Text = 'Joint initial positions';
            app.PIDpositioncontrolLabel = uilabel(app.PIDpositioncontrolTab);
            app.PIDpositioncontrolLabel.Text = 'PID position control';
            app.DesiredtrajectoryLabel = uilabel(app.PIDpositioncontrolTab);
            app.DesiredtrajectoryLabel.Text = 'Desired trajectory';
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.RobotpositionSwitchLabel = uilabel(app.SettingsTab);
            app.RobotpositionSwitchLabel.Text = 'Robot position';
            app.RobotpositionSwitch = uiswitch(app.SettingsTab, 'slider');
            app.PositionsreadingTab = uitab(app.TabGroup2);
            app.PositionsreadingTab.Title = 'Positions reading';
            app.ReadButton = uibutton(app.PositionsreadingTab, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Enable = 'off';
            app.ReadButton.Text = 'Read';
            app.Joint1angleLabel = uilabel(app.PositionsreadingTab);
            app.Joint1angleLabel.Text = 'Joint 1 angle:';
            app.Joint1rad = uilabel(app.PositionsreadingTab);
            app.Joint1rad.Text = '0';
            app.Joint2angleLabel = uilabel(app.PositionsreadingTab);
            app.Joint2angleLabel.Text = 'Joint 2 angle:';
            app.Joint2rad = uilabel(app.PositionsreadingTab);
            app.Joint2rad.Text = '0';
            app.radsLabel = uilabel(app.PositionsreadingTab);
            app.radsLabel.Text = 'rad(s)';
            app.radsLabel_2 = uilabel(app.PositionsreadingTab);
            app.radsLabel_2.Text = 'rad(s)';
            app.Joint1degree = uilabel(app.PositionsreadingTab);
            app.Joint1degree.Text = '0';
            app.degreesLabel = uilabel(app.PositionsreadingTab);
            app.degreesLabel.Text = 'degree(s)';
            app.Joint2degree = uilabel(app.PositionsreadingTab);
            app.Joint2degree.Text = '0';
            app.degreesLabel_2 = uilabel(app.PositionsreadingTab);
            app.degreesLabel_2.Text = 'degree(s)';
            app.ReadpositionsonceLabel = uilabel(app.PositionsreadingTab);
            app.ReadpositionsonceLabel.Text = 'Read positions once';
            app.ReadforawhileLabel = uilabel(app.PositionsreadingTab);
            app.ReadforawhileLabel.Text = 'Read for a while';
            app.PositionsangleswillbeplottedinthepanelbelowLabel = uilabel(app.PositionsreadingTab);
            app.PositionsangleswillbeplottedinthepanelbelowLabel.Text = 'Positions/angles will be plotted in the panel below.';
            app.ReadandplotSwitchLabel = uilabel(app.PositionsreadingTab);
            app.ReadandplotSwitchLabel.Text = 'Read and plot';
            app.ReadandplotSwitch = uiswitch(app.PositionsreadingTab, 'toggle');
            app.ReadandplotSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadandplotSwitchValueChanged, true);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2Label = uilabel(app.UIFigure);
            app.Lamp2Label.Text = 'Lamp2';
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp3Label = uilabel(app.UIFigure);
            app.Lamp3Label.Text = 'Lamp3';
            app.Lamp3 = uilamp(app.UIFigure);
            app.CommandOptionsLabel = uilabel(app.UIFigure);
            app.CommandOptionsLabel.Text = 'Command Options';
            app.COMportEditFieldLabel = uilabel(app.UIFigure);
            app.COMportEditFieldLabel.Text = 'COM port';
            app.COMportEditField = uieditfield(app.UIFigure, 'text');
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.GUIforR2RRobotLabel = uilabel(app.UIFigure);
            app.GUIforR2RRobotLabel.Text = 'GUI for R2R Robot';
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Simulation')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes5.XLim = [0 1];
            app.UIAxes5.YLim = [0 1];
            app.UIAxes5.ZLim = [0 1];
            app.UIAxes5.CLim = [0 1];
            app.UIAxes5.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes5.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes5.ZTick = [0 0.5 1];
            app.CalibrationButton = uibutton(app.UIFigure, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Enable = 'off';
            app.CalibrationButton.Text = 'Calibration';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end